classdef UnitConverter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UnitConverterUIFigure     matlab.ui.Figure
        UnitConverterLabel        matlab.ui.control.Label
        InputValueField           matlab.ui.control.NumericEditField
        ConvertButton             matlab.ui.control.Button
        UnitDropDownLabel         matlab.ui.control.Label
        UnitDropDown              matlab.ui.control.DropDown
        EqualSign                 matlab.ui.control.Label
        ConvertedUnitLabel        matlab.ui.control.Label
        ErrorMessage              matlab.ui.control.Label
        ChooseTypeListBoxLabel    matlab.ui.control.Label
        ChooseTypeListBox         matlab.ui.control.ListBox
        ChooseSystemListBox       matlab.ui.control.ListBox
        ChooseSystemListBoxLabel  matlab.ui.control.Label
        InputValueLabel           matlab.ui.control.Label
        ConvertedValueLabel       matlab.ui.control.Label
        ConvertedValue            matlab.ui.control.EditField
        ClearButton               matlab.ui.control.Button
        About                     matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConvertButton
        function ConvertButtonPushed(app, event)
            [output_value, output_unit, error_message] = convertUnits(app);
            
            app.ConvertedValue.Value = output_value;
            app.ConvertedUnitLabel.Text = output_unit;
            app.ErrorMessage.Text = error_message;
        end

        % Value changed function: ChooseTypeListBox
        function ChooseTypeListBoxValueChanged(app, event)
            app.UnitDropDown.Items = setUnitItems(app);
        end

        % Value changed function: ChooseSystemListBox
        function ChooseSystemListBoxValueChanged(app, event)
            app.UnitDropDown.Items = setUnitItems(app);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.InputValueField.Value = 0;
            app.ConvertedValue.Value = '';
            app.ConvertedUnitLabel.Text = '';
            app.ClearButton.Tooltip = 'Clear';
            app.ErrorMessage.Text = '';
        end

        % Value changed function: ConvertedValue
        function ConvertedValueValueChanged(app, event)
            app.ConvertedValue.Editable = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UnitConverterUIFigure = uifigure('Visible', 'off');
            app.UnitConverterUIFigure.Name = 'Unit Converter';
            app.UnitConverterUIFigure.Tag = 'Individual Project, Nguyen Quoc Hoang, s3697305';
            app.UnitConverterLabel = uilabel(app.UnitConverterUIFigure);
            app.UnitConverterLabel.Text = 'Unit Converter';
            app.InputValueField = uieditfield(app.UnitConverterUIFigure, 'numeric');
            app.ConvertButton = uibutton(app.UnitConverterUIFigure, 'push');
            app.ConvertButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertButtonPushed, true);
            app.ConvertButton.Text = 'Convert!';
            app.UnitDropDownLabel = uilabel(app.UnitConverterUIFigure);
            app.UnitDropDownLabel.Text = 'Unit';
            app.UnitDropDown = uidropdown(app.UnitConverterUIFigure);
            app.UnitDropDown.Items = {'°C'};
            app.UnitDropDown.Value = '°C';
            app.EqualSign = uilabel(app.UnitConverterUIFigure);
            app.EqualSign.Text = '=';
            app.ConvertedUnitLabel = uilabel(app.UnitConverterUIFigure);
            app.ConvertedUnitLabel.Text = '';
            app.ErrorMessage = uilabel(app.UnitConverterUIFigure);
            app.ErrorMessage.Text = '';
            app.ChooseTypeListBoxLabel = uilabel(app.UnitConverterUIFigure);
            app.ChooseTypeListBoxLabel.Text = 'Choose Type';
            app.ChooseTypeListBox = uilistbox(app.UnitConverterUIFigure);
            app.ChooseTypeListBox.Items = {'Temperature', 'Length and Distance', 'Mass'};
            app.ChooseTypeListBox.ValueChangedFcn = createCallbackFcn(app, @ChooseTypeListBoxValueChanged, true);
            app.ChooseTypeListBox.Value = 'Temperature';
            app.ChooseSystemListBox = uilistbox(app.UnitConverterUIFigure);
            app.ChooseSystemListBox.Items = {'Metric', 'Imperial'};
            app.ChooseSystemListBox.ValueChangedFcn = createCallbackFcn(app, @ChooseSystemListBoxValueChanged, true);
            app.ChooseSystemListBox.Value = 'Metric';
            app.ChooseSystemListBoxLabel = uilabel(app.UnitConverterUIFigure);
            app.ChooseSystemListBoxLabel.Text = 'Choose System';
            app.InputValueLabel = uilabel(app.UnitConverterUIFigure);
            app.InputValueLabel.Text = 'Input Value';
            app.ConvertedValueLabel = uilabel(app.UnitConverterUIFigure);
            app.ConvertedValueLabel.Text = 'Converted Value';
            app.ConvertedValue = uieditfield(app.UnitConverterUIFigure, 'text');
            app.ConvertedValue.ValueChangedFcn = createCallbackFcn(app, @ConvertedValueValueChanged, true);
            app.ClearButton = uibutton(app.UnitConverterUIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'x';
            app.About = uilabel(app.UnitConverterUIFigure);
            app.About.Enable = 'off';
            app.About.Text = 'Inividual Project | Nguyen Quoc Hoang - s3697305';
            % Show the figure after all components are created
            app.UnitConverterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UnitConverter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UnitConverterUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UnitConverterUIFigure)
        end
    end
end